classdef CONTROL1_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        uipanel5     matlab.ui.container.Panel
        edit13       matlab.ui.control.EditField
        edit12       matlab.ui.control.EditField
        edit11       matlab.ui.control.EditField
        text11       matlab.ui.control.Label
        text10       matlab.ui.control.Label
        text9        matlab.ui.control.Label
        slider9      matlab.ui.control.Slider
        slider8      matlab.ui.control.Slider
        slider7      matlab.ui.control.Slider
        uipanel4     matlab.ui.container.Panel
        INVERSE      matlab.ui.control.Button
        STOP         matlab.ui.control.Button
        RUN          matlab.ui.control.Button
        pushbutton2  matlab.ui.control.Button
        uipanel3     matlab.ui.container.Panel
        text8        matlab.ui.control.Label
        text7        matlab.ui.control.Label
        text6        matlab.ui.control.Label
        edit10       matlab.ui.control.EditField
        edit9        matlab.ui.control.EditField
        edit8        matlab.ui.control.EditField
        uipanel2     matlab.ui.container.Panel
        edit20       matlab.ui.control.EditField
        edit19       matlab.ui.control.EditField
        edit18       matlab.ui.control.EditField
        edit17       matlab.ui.control.EditField
        edit16       matlab.ui.control.EditField
        edit15       matlab.ui.control.EditField
        edit7        matlab.ui.control.EditField
        edit6        matlab.ui.control.EditField
        slider6      matlab.ui.control.Slider
        slider5      matlab.ui.control.Slider
        edit5        matlab.ui.control.EditField
        edit4        matlab.ui.control.EditField
        edit3        matlab.ui.control.EditField
        edit2        matlab.ui.control.EditField
        slider4      matlab.ui.control.Slider
        slider3      matlab.ui.control.Slider
        slider2      matlab.ui.control.Slider
        slider1      matlab.ui.control.Slider
    end

    
    methods (Access = private)
        function edit12_CreateFcn(app, hObject, eventdata, handles)
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit13_CreateFcn(app, hObject, eventdata, handles)
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit1_CreateFcn(app, hObject, eventdata, handles)
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function slider8_CreateFcn(app, hObject, eventdata, handles)
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function slider9_CreateFcn(app, hObject, eventdata, handles)
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CONTROL1_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            handles.output = hObject;
            guidata(hObject, handles);
            clc;
        end

        % Button pushed function: INVERSE
        function INVERSE_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            px=get(handles.slider7,'value');
            py=get(handles.slider8,'value');
            pz=get(handles.slider9,'value');
            %---------------------------------------------
            set(handles.edit11,'string',num2str(px));
            set(handles.edit12,'string',num2str(py));
            set(handles.edit13,'string',num2str(pz));
            set(handles.edit8,'string',num2str(px));
            set(handles.edit9,'string',num2str(py));
            set(handles.edit10,'string',num2str(pz));
            %---------------------------------------------
            [theta1, theta2, theta3, theta4, theta5, theta6]=...
                Inv_Kinematic(px,py,pz);
            %---------------------------------------------
            ModelName = 'Assem1';
            guidata(hObject,handles);
            set_param([ModelName '/Slider Gain1'],'Gain',num2str(theta1));
            set_param([ModelName '/Slider Gain2'],'Gain',num2str(theta2));
            set_param([ModelName '/Slider Gain3'],'Gain',num2str(theta3));
            set_param([ModelName '/Slider Gain4'],'Gain',num2str(theta4));
            set_param([ModelName '/Slider Gain5'],'Gain',num2str(theta5));
            set_param([ModelName '/Slider Gain6'],'Gain',num2str(theta6));
            %---------------------------------------------
            set(handles.edit2,'string',num2str(theta1));
            set(handles.edit3,'string',num2str(theta2));
            set(handles.edit4,'string',num2str(theta3));
            set(handles.edit5,'string',num2str(theta4));
            set(handles.edit6,'string',num2str(theta5));
            set(handles.edit7,'string',num2str(theta6));
        end

        % Button pushed function: RUN
        function RUN_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ModelName = 'Assem1';
            open_system(ModelName);
            set_param(ModelName,'BlockReduction','off');
            set_param(ModelName,'StopTime','inf');
            set_param(ModelName,'simulationMode','normal');
            set_param(ModelName,'StartFcn','1');
            set_param(ModelName, 'SimulationCommand', 'start');
        end

        % Button pushed function: STOP
        function STOP_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            close;
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            theta1 = 0; theta2 = 0; theta3 = 0;
            theta4 = 0; theta5 = 0; theta6 = 0;
            %---------------------------------------------
            [px,py,pz]=Fow_Kinematic(theta1,...
                theta2, theta3, theta4, theta5, theta6);
            %---------------------------------------------
            ModelName = 'Assem1';
            set_param([ModelName '/Slider Gain1'],'Gain',num2str(theta1));
            set_param([ModelName '/Slider Gain2'],'Gain',num2str(theta2));
            set_param([ModelName '/Slider Gain3'],'Gain',num2str(theta3));
            set_param([ModelName '/Slider Gain4'],'Gain',num2str(theta4));
            set_param([ModelName '/Slider Gain5'],'Gain',num2str(theta5));
            set_param([ModelName '/Slider Gain6'],'Gain',num2str(theta6));
            %---------------------------------------------
            set(handles.slider1,'value',theta1);
            set(handles.slider2,'value',theta2);
            set(handles.slider3,'value',theta3);
            set(handles.slider4,'value',theta4);
            set(handles.slider5,'value',theta5);
            set(handles.slider6,'value',theta6);
            %---------------------------------------------
            set(handles.edit2,'string',num2str(0));
            set(handles.edit3,'string',num2str(0));
            set(handles.edit4,'string',num2str(0));
            set(handles.edit5,'string',num2str(0));
            set(handles.edit6,'string',num2str(0));
            set(handles.edit7,'string',num2str(0));
            set(handles.edit8,'string',num2str(px));
            set(handles.edit9,'string',num2str(py));
            set(handles.edit10,'string',num2str(pz));
        end

        % Value changed function: slider1
        function slider1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            ModelName = 'Assem1';
            %---------------------------------------------
            theta1=get(handles.slider1,'value');
            theta2=get(handles.slider2,'value');
            theta3=get(handles.slider3,'value');
            theta4=get(handles.slider4,'value');
            theta5=get(handles.slider5,'value');
            theta6=get(handles.slider6,'value');
            %---------------------------------------------
            set(handles.edit2,'string',num2str(theta1));
            set(handles.edit3,'string',num2str(theta2));
            set(handles.edit4,'string',num2str(theta3));
            set(handles.edit5,'string',num2str(theta4));
            set(handles.edit6,'string',num2str(theta5));
            set(handles.edit7,'string',num2str(theta6));
            %---------------------------------------------
            set_param([ModelName '/Slider Gain1'],'Gain',num2str(theta1));
            set_param([ModelName '/Slider Gain2'],'Gain',num2str(theta2));
            set_param([ModelName '/Slider Gain3'],'Gain',num2str(theta3));
            set_param([ModelName '/Slider Gain4'],'Gain',num2str(theta4));
            set_param([ModelName '/Slider Gain5'],'Gain',num2str(theta5));
            set_param([ModelName '/Slider Gain6'],'Gain',num2str(theta6));
            %---------------------------------------------
            [px,py,pz]=Fow_Kinematic(theta1,...
                theta2, theta3, theta4, theta5, theta6);
            %---------------------------------------------
            set(handles.edit8,'string',num2str(px));
            set(handles.edit9,'string',num2str(py));
            set(handles.edit10,'string',num2str(pz));
        end

        % Value changed function: slider2
        function slider2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ModelName = 'Assem1';
            %---------------------------------------------
            theta1=get(handles.slider1,'value');
            theta2=get(handles.slider2,'value');
            theta3=get(handles.slider3,'value');
            theta4=get(handles.slider4,'value');
            theta5=get(handles.slider5,'value');
            theta6=get(handles.slider6,'value');
            %---------------------------------------------
            set(handles.edit2,'string',num2str(theta1));
            set(handles.edit3,'string',num2str(theta2));
            set(handles.edit4,'string',num2str(theta3));
            set(handles.edit5,'string',num2str(theta4));
            set(handles.edit6,'string',num2str(theta5));
            set(handles.edit7,'string',num2str(theta6));
            %---------------------------------------------
            set_param([ModelName '/Slider Gain1'],'Gain',num2str(theta1));
            set_param([ModelName '/Slider Gain2'],'Gain',num2str(theta2));
            set_param([ModelName '/Slider Gain3'],'Gain',num2str(theta3));
            set_param([ModelName '/Slider Gain4'],'Gain',num2str(theta4));
            set_param([ModelName '/Slider Gain5'],'Gain',num2str(theta5));
            set_param([ModelName '/Slider Gain6'],'Gain',num2str(theta6));
            %---------------------------------------------
            [px,py,pz]=Fow_Kinematic(theta1,...
                theta2, theta3, theta4, theta5, theta6);
            %---------------------------------------------
            set(handles.edit8,'string',num2str(px));
            set(handles.edit9,'string',num2str(py));
            set(handles.edit10,'string',num2str(pz));
        end

        % Value changed function: slider3
        function slider3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ModelName = 'Assem1';
            %---------------------------------------------
            theta1=get(handles.slider1,'value');
            theta2=get(handles.slider2,'value');
            theta3=get(handles.slider3,'value');
            theta4=get(handles.slider4,'value');
            theta5=get(handles.slider5,'value');
            theta6=get(handles.slider6,'value');
            %---------------------------------------------
            set(handles.edit2,'string',num2str(theta1));
            set(handles.edit3,'string',num2str(theta2));
            set(handles.edit4,'string',num2str(theta3));
            set(handles.edit5,'string',num2str(theta4));
            set(handles.edit6,'string',num2str(theta5));
            set(handles.edit7,'string',num2str(theta6));
            %---------------------------------------------
            set_param([ModelName '/Slider Gain1'],'Gain',num2str(theta1));
            set_param([ModelName '/Slider Gain2'],'Gain',num2str(theta2));
            set_param([ModelName '/Slider Gain3'],'Gain',num2str(theta3));
            set_param([ModelName '/Slider Gain4'],'Gain',num2str(theta4));
            set_param([ModelName '/Slider Gain5'],'Gain',num2str(theta5));
            set_param([ModelName '/Slider Gain6'],'Gain',num2str(theta6));
            %---------------------------------------------
            [px,py,pz]=Fow_Kinematic(theta1,...
                theta2, theta3, theta4, theta5, theta6);
            %---------------------------------------------
            set(handles.edit8,'string',num2str(px));
            set(handles.edit9,'string',num2str(py));
            set(handles.edit10,'string',num2str(pz));
        end

        % Value changed function: slider4
        function slider4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ModelName = 'Assem1';
            %---------------------------------------------
            theta1=get(handles.slider1,'value');
            theta2=get(handles.slider2,'value');
            theta3=get(handles.slider3,'value');
            theta4=get(handles.slider4,'value');
            theta5=get(handles.slider5,'value');
            theta6=get(handles.slider6,'value');
            %---------------------------------------------
            set(handles.edit2,'string',num2str(theta1));
            set(handles.edit3,'string',num2str(theta2));
            set(handles.edit4,'string',num2str(theta3));
            set(handles.edit5,'string',num2str(theta4));
            set(handles.edit6,'string',num2str(theta5));
            set(handles.edit7,'string',num2str(theta6));
            %---------------------------------------------
            set_param([ModelName '/Slider Gain1'],'Gain',num2str(theta1));
            set_param([ModelName '/Slider Gain2'],'Gain',num2str(theta2));
            set_param([ModelName '/Slider Gain3'],'Gain',num2str(theta3));
            set_param([ModelName '/Slider Gain4'],'Gain',num2str(theta4));
            set_param([ModelName '/Slider Gain5'],'Gain',num2str(theta5));
            set_param([ModelName '/Slider Gain6'],'Gain',num2str(theta6));
            %---------------------------------------------
            [px,py,pz]=Fow_Kinematic(theta1,...
                theta2, theta3, theta4, theta5, theta6);
            %---------------------------------------------
            set(handles.edit8,'string',num2str(px));
            set(handles.edit9,'string',num2str(py));
            set(handles.edit10,'string',num2str(pz));
        end

        % Value changed function: slider5
        function slider5_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ModelName = 'Assem1';
            %---------------------------------------------
            theta1=get(handles.slider1,'value');
            theta2=get(handles.slider2,'value');
            theta3=get(handles.slider3,'value');
            theta4=get(handles.slider4,'value');
            theta5=get(handles.slider5,'value');
            theta6=get(handles.slider6,'value');
            %---------------------------------------------
            set(handles.edit2,'string',num2str(theta1));
            set(handles.edit3,'string',num2str(theta2));
            set(handles.edit4,'string',num2str(theta3));
            set(handles.edit5,'string',num2str(theta4));
            set(handles.edit6,'string',num2str(theta5));
            set(handles.edit7,'string',num2str(theta6));
            %---------------------------------------------
            set_param([ModelName '/Slider Gain1'],'Gain',num2str(theta1));
            set_param([ModelName '/Slider Gain2'],'Gain',num2str(theta2));
            set_param([ModelName '/Slider Gain3'],'Gain',num2str(theta3));
            set_param([ModelName '/Slider Gain4'],'Gain',num2str(theta4));
            set_param([ModelName '/Slider Gain5'],'Gain',num2str(theta5));
            set_param([ModelName '/Slider Gain6'],'Gain',num2str(theta6));
            %---------------------------------------------
            [px,py,pz]=Fow_Kinematic(theta1,...
                theta2, theta3, theta4, theta5, theta6);
            %---------------------------------------------
            set(handles.edit8,'string',num2str(px));
            set(handles.edit9,'string',num2str(py));
            set(handles.edit10,'string',num2str(pz));
        end

        % Value changed function: slider6
        function slider6_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ModelName = 'Assem1';
            %---------------------------------------------
            theta1=get(handles.slider1,'value');
            theta2=get(handles.slider2,'value');
            theta3=get(handles.slider3,'value');
            theta4=get(handles.slider4,'value');
            theta5=get(handles.slider5,'value');
            theta6=get(handles.slider6,'value');
            %---------------------------------------------
            set(handles.edit2,'string',num2str(theta1));
            set(handles.edit3,'string',num2str(theta2));
            set(handles.edit4,'string',num2str(theta3));
            set(handles.edit5,'string',num2str(theta4));
            set(handles.edit6,'string',num2str(theta5));
            set(handles.edit7,'string',num2str(theta6));
            %---------------------------------------------
            set_param([ModelName '/Slider Gain1'],'Gain',num2str(theta1));
            set_param([ModelName '/Slider Gain2'],'Gain',num2str(theta2));
            set_param([ModelName '/Slider Gain3'],'Gain',num2str(theta3));
            set_param([ModelName '/Slider Gain4'],'Gain',num2str(theta4));
            set_param([ModelName '/Slider Gain5'],'Gain',num2str(theta5));
            set_param([ModelName '/Slider Gain6'],'Gain',num2str(theta6));
            %---------------------------------------------
            [px,py,pz]=Fow_Kinematic(theta1,...
                theta2, theta3, theta4, theta5, theta6);
            %---------------------------------------------
            set(handles.edit8,'string',num2str(px));
            set(handles.edit9,'string',num2str(py));
            set(handles.edit10,'string',num2str(pz));
        end

        % Value changed function: slider7
        function slider7_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            px=get(handles.slider7,'value');
            set(handles.edit11,'string',num2str(px));
        end

        % Value changed function: slider8
        function slider8_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            py=get(handles.slider8,'value');
            set(handles.edit12,'string',num2str(py));
        end

        % Value changed function: slider9
        function slider9_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            pz=get(handles.slider9,'value');
            set(handles.edit13,'string',num2str(pz));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'CONTROL';
            app.figure1.Tag = 'figure1';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Forward kinematic';
            app.uipanel2.Tag = 'uipanel2';
            app.slider1 = uislider(app.uipanel2);
            app.slider1.Limits = [-180 180];
            app.slider1.MajorTicks = [];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [];
            app.slider1.Tag = 'slider1';
            app.slider2 = uislider(app.uipanel2);
            app.slider2.Limits = [-180 180];
            app.slider2.MajorTicks = [];
            app.slider2.ValueChangedFcn = createCallbackFcn(app, @slider2_Callback, true);
            app.slider2.MinorTicks = [];
            app.slider2.Tag = 'slider2';
            app.slider3 = uislider(app.uipanel2);
            app.slider3.Limits = [-180 180];
            app.slider3.MajorTicks = [];
            app.slider3.ValueChangedFcn = createCallbackFcn(app, @slider3_Callback, true);
            app.slider3.MinorTicks = [];
            app.slider3.Tag = 'slider3';
            app.slider4 = uislider(app.uipanel2);
            app.slider4.Limits = [-180 180];
            app.slider4.MajorTicks = [];
            app.slider4.ValueChangedFcn = createCallbackFcn(app, @slider4_Callback, true);
            app.slider4.MinorTicks = [];
            app.slider4.Tag = 'slider4';
            app.edit2 = uieditfield(app.uipanel2, 'text');
            app.edit2.Tag = 'edit2';
            app.edit3 = uieditfield(app.uipanel2, 'text');
            app.edit3.Tag = 'edit3';
            app.edit4 = uieditfield(app.uipanel2, 'text');
            app.edit4.Tag = 'edit4';
            app.edit5 = uieditfield(app.uipanel2, 'text');
            app.edit5.Tag = 'edit5';
            app.slider5 = uislider(app.uipanel2);
            app.slider5.Limits = [-180 180];
            app.slider5.MajorTicks = [];
            app.slider5.ValueChangedFcn = createCallbackFcn(app, @slider5_Callback, true);
            app.slider5.MinorTicks = [];
            app.slider5.Tag = 'slider5';
            app.slider6 = uislider(app.uipanel2);
            app.slider6.Limits = [-180 180];
            app.slider6.MajorTicks = [];
            app.slider6.ValueChangedFcn = createCallbackFcn(app, @slider6_Callback, true);
            app.slider6.MinorTicks = [];
            app.slider6.Tag = 'slider6';
            app.edit6 = uieditfield(app.uipanel2, 'text');
            app.edit6.Tag = 'edit6';
            app.edit7 = uieditfield(app.uipanel2, 'text');
            app.edit7.Tag = 'edit7';
            app.edit15 = uieditfield(app.uipanel2, 'text');
            app.edit15.Tag = 'edit15';
            app.edit15.Value = 'Axis 1';
            app.edit16 = uieditfield(app.uipanel2, 'text');
            app.edit16.Tag = 'edit16';
            app.edit16.Value = 'Axis 2 ';
            app.edit17 = uieditfield(app.uipanel2, 'text');
            app.edit17.Tag = 'edit17';
            app.edit17.Value = 'Axis 3';
            app.edit18 = uieditfield(app.uipanel2, 'text');
            app.edit18.Tag = 'edit18';
            app.edit18.Value = 'Axis 4';
            app.edit19 = uieditfield(app.uipanel2, 'text');
            app.edit19.Tag = 'edit19';
            app.edit19.Value = 'Axis 5';
            app.edit20 = uieditfield(app.uipanel2, 'text');
            app.edit20.Tag = 'edit20';
            app.edit20.Value = 'End-effector';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Position';
            app.uipanel3.Tag = 'uipanel3';
            app.edit8 = uieditfield(app.uipanel3, 'text');
            app.edit8.Tag = 'edit8';
            app.edit9 = uieditfield(app.uipanel3, 'text');
            app.edit9.Tag = 'edit9';
            app.edit10 = uieditfield(app.uipanel3, 'text');
            app.edit10.Tag = 'edit10';
            app.text6 = uilabel(app.uipanel3);
            app.text6.Tag = 'text6';
            app.text6.Text = 'X';
            app.text7 = uilabel(app.uipanel3);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Y';
            app.text8 = uilabel(app.uipanel3);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Z';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Mode';
            app.uipanel4.Tag = 'uipanel4';
            app.pushbutton2 = uibutton(app.uipanel4, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = 'DEFAULT';
            app.RUN = uibutton(app.uipanel4, 'push');
            app.RUN.ButtonPushedFcn = createCallbackFcn(app, @RUN_Callback, true);
            app.RUN.Tag = 'RUN';
            app.RUN.Text = 'RUN';
            app.STOP = uibutton(app.uipanel4, 'push');
            app.STOP.ButtonPushedFcn = createCallbackFcn(app, @STOP_Callback, true);
            app.STOP.Tag = 'STOP';
            app.STOP.Text = 'STOP';
            app.INVERSE = uibutton(app.uipanel4, 'push');
            app.INVERSE.ButtonPushedFcn = createCallbackFcn(app, @INVERSE_Callback, true);
            app.INVERSE.Tag = 'INVERSE';
            app.INVERSE.Text = 'INVERSE';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'Inverse kinematic';
            app.uipanel5.Tag = 'uipanel5';
            app.slider7 = uislider(app.uipanel5);
            app.slider7.Limits = [-1000 1000];
            app.slider7.MajorTicks = [];
            app.slider7.ValueChangedFcn = createCallbackFcn(app, @slider7_Callback, true);
            app.slider7.MinorTicks = [];
            app.slider7.Tag = 'slider7';
            app.slider8 = uislider(app.uipanel5);
            app.slider8.Limits = [-1000 1000];
            app.slider8.MajorTicks = [];
            app.slider8.ValueChangedFcn = createCallbackFcn(app, @slider8_Callback, true);
            app.slider8.MinorTicks = [];
            app.slider8.Tag = 'slider8';
            app.slider9 = uislider(app.uipanel5);
            app.slider9.Limits = [-1000 1000];
            app.slider9.MajorTicks = [];
            app.slider9.ValueChangedFcn = createCallbackFcn(app, @slider9_Callback, true);
            app.slider9.MinorTicks = [];
            app.slider9.Tag = 'slider9';
            app.text9 = uilabel(app.uipanel5);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Px';
            app.text10 = uilabel(app.uipanel5);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Py';
            app.text11 = uilabel(app.uipanel5);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Pz';
            app.edit11 = uieditfield(app.uipanel5, 'text');
            app.edit11.Tag = 'edit11';
            app.edit12 = uieditfield(app.uipanel5, 'text');
            app.edit12.Tag = 'edit12';
            app.edit13 = uieditfield(app.uipanel5, 'text');
            app.edit13.Tag = 'edit13';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CONTROL1_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)CONTROL1_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end